classdef DCIMG2TIFF_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCIMGtoTIFFConverterUIFigure  matlab.ui.Figure
        FlipHorizontalCheckBox        matlab.ui.control.CheckBox
        CurrentDirectory              matlab.ui.control.TextArea
        DirectoryTextAreaLabel        matlab.ui.control.Label
        ChoosefilesButton             matlab.ui.control.Button
        FileListBox                   matlab.ui.control.ListBox
        FilestoconvertLabel           matlab.ui.control.Label
        ConvertButton                 matlab.ui.control.Button
        LogBox                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        function addLogText(app,text)
            textToAdd = sprintf("> %s",text);
            app.LogBox.Value = [textToAdd; app.LogBox.Value];
        end
        
        function setDirectory(app,FilePath)
            app.CurrentDirectory.Value = FilePath;
            cd(FilePath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addLogText("Please select your DCIMG files for conversion to TIFF format.");
        end

        % Button pushed function: ChoosefilesButton
        function ChoosefilesButtonPushed(app, event)
            [Files,FilePath] = uigetfile(".dcimg","MultiSelect","on");
            if ~iscell(Files)
                Files = {Files};
            end
            app.FileListBox.Items = Files;
            app.setDirectory(FilePath)
            app.addLogText("Files selected.");
        end

        % Value changed function: FlipHorizontalCheckBox
        function FlipHorizontalCheckBoxValueChanged(app, event)

        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            app.addLogText("Converting files...")
            Flip = app.FlipHorizontalCheckBox.Value; % Whether to flip image horizontally (typically needed on Walter lab Prism TIRF 1)
            Files = app.FileListBox.Items;
            Dir = app.CurrentDirectory.Value{1};
            for n = 1:numel(Files)
                InFileName = strcat(Dir,filesep,Files{n});
                [~, BaseName, ~] = fileparts(InFileName);
                OutFileName = strcat(Dir,filesep,BaseName,'.tif');
                dcimg_to_tiff(InFileName,OutFileName,Flip);
                app.addLogText(strjoin(["Finished file ", num2str(n), " of ", num2str(numel(Files)),"."],''));
            end
            app.addLogText('Finished all files.');
        end

        % Value changed function: CurrentDirectory
        function CurrentDirectoryValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCIMGtoTIFFConverterUIFigure = uifigure('Visible', 'off');
            app.DCIMGtoTIFFConverterUIFigure.Name = 'DCIMG to TIFF Converter';
            app.LogBox = uitextarea(app.DCIMGtoTIFFConverterUIFigure);
            app.LogBox.Editable = 'off';
            app.ConvertButton = uibutton(app.DCIMGtoTIFFConverterUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.FilestoconvertLabel = uilabel(app.DCIMGtoTIFFConverterUIFigure);
            app.FilestoconvertLabel.Text = 'Files to convert:';
            app.FileListBox = uilistbox(app.DCIMGtoTIFFConverterUIFigure);
            app.FileListBox.Items = {'(none selected)'};
            app.FileListBox.Value = '(none selected)';
            app.ChoosefilesButton = uibutton(app.DCIMGtoTIFFConverterUIFigure, 'push');
            app.ChoosefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilesButtonPushed, true);
            app.ChoosefilesButton.Text = 'Choose files....';
            app.DirectoryTextAreaLabel = uilabel(app.DCIMGtoTIFFConverterUIFigure);
            app.DirectoryTextAreaLabel.Text = 'Directory:';
            app.CurrentDirectory = uitextarea(app.DCIMGtoTIFFConverterUIFigure);
            app.CurrentDirectory.ValueChangedFcn = createCallbackFcn(app, @CurrentDirectoryValueChanged, true);
            app.CurrentDirectory.Editable = 'off';
            app.FlipHorizontalCheckBox = uicheckbox(app.DCIMGtoTIFFConverterUIFigure);
            app.FlipHorizontalCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipHorizontalCheckBoxValueChanged, true);
            app.FlipHorizontalCheckBox.Text = 'Flip image horizontally';
            app.FlipHorizontalCheckBox.Value = true;
            % Show the figure after all components are created
            app.DCIMGtoTIFFConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCIMG2TIFF_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCIMGtoTIFFConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCIMGtoTIFFConverterUIFigure)
        end
    end
end